classdef ProjectDijkstra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        EditFieldLabel     matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        CreateGraphButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        A = zeros % Adj matrix
        G = graph % Graph
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.UITable.Data = zeros(value, value); 
            app.UITable.ColumnName = 1:value;
            app.UITable.RowName = 1:value;
            app.UITable.ColumnWidth = {30, 30, 30, 30, 30, 30, 30, 30, 30, 30};
            app.UITable.Visible = "on";
            app.UITable.Enable = "on";

            app.A = zeros(value);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1)
            col = event.Indices(2)
            newData = event.NewData;
            
            if (row == col)
                uialert(app.UIFigure, "The diagonal weights must be 0", "Title", "Icon", "info")
                app.A(row, col) = 0;
            else
                app.A(row, col) = newData;
                app.A(col, row) = newData;
            end
            
            app.UITable.Data = app.A;             
        end

        % Button pushed function: CreateGraphButton
        function CreateGraphButtonPushed(app, event)
            app.G = graph(app.A);
            plot(app.UIAxes, app.G,'EdgeLabel', app.G.Edges.Weight)
            %fplot(app.UIAxes, app.G);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tooltip = {''; ''; ''};
            app.UITable.Enable = 'off';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 10];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.CreateGraphButton = uibutton(app.UIFigure, 'push');
            app.CreateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGraphButtonPushed, true);
            app.CreateGraphButton.Text = 'Create Graph';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weighted graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectDijkstra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end